classdef ADRIA_app_example_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ADRIAOutputDisplayUIFigure     matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Panel2_6                       matlab.ui.container.Panel
        MCDADropDown                   matlab.ui.control.DropDown
        MCDADropDownLabel              matlab.ui.control.Label
        AnalysescenarioButton          matlab.ui.control.Button
        RCPKnob                        matlab.ui.control.DiscreteKnob
        RCPKnobLabel                   matlab.ui.control.Label
        Panel2_5                       matlab.ui.container.Panel
        NatAdtDropDown                 matlab.ui.control.DropDown
        NatAdtDropDownLabel            matlab.ui.control.Label
        AssAdtDropDown                 matlab.ui.control.DropDown
        AssAdtDropDownLabel            matlab.ui.control.Label
        ScenarioSelectionLabel         matlab.ui.control.Label
        Panel2_4                       matlab.ui.container.Panel
        SRMDropDown                    matlab.ui.control.DropDown
        SRMDropDownLabel               matlab.ui.control.Label
        Seed2DropDown                  matlab.ui.control.DropDown
        Seed2DropDownLabel             matlab.ui.control.Label
        Seed1DropDown                  matlab.ui.control.DropDown
        Seed1DropDownLabel             matlab.ui.control.Label
        Panel2_2                       matlab.ui.container.Panel
        BCheckBox                      matlab.ui.control.CheckBox
        ACheckBox                      matlab.ui.control.CheckBox
        CCheckBox_2                    matlab.ui.control.CheckBox
        Panel2                         matlab.ui.container.Panel
        YesCheckBox_2                  matlab.ui.control.CheckBox
        NoCheckBox_2                   matlab.ui.control.CheckBox
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        TimeSeriesTab                  matlab.ui.container.Tab
        MetricButtonGroup              matlab.ui.container.ButtonGroup
        ScopeforProvisionalESButton    matlab.ui.control.RadioButton
        ScopeforCulturalESButton       matlab.ui.control.RadioButton
        CoralCoverButton               matlab.ui.control.RadioButton
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        BBNTab                         matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        InterventionInferenceTab       matlab.ui.container.Tab
        WithoutInterventionLabel       matlab.ui.control.Label
        WithInterventionLabel          matlab.ui.control.Label
        TextArea_4                     matlab.ui.control.TextArea
        MetricButtonGroup_2            matlab.ui.container.ButtonGroup
        ScopeforProvisionalESButton_2  matlab.ui.control.RadioButton
        ScopeforCulturalESButton_2     matlab.ui.control.RadioButton
        CoralCoverButton_2             matlab.ui.control.RadioButton
        TextArea_3                     matlab.ui.control.TextArea
        TextArea_3Label                matlab.ui.control.Label
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        MetricInferenceTab             matlab.ui.container.Tab
        NatAdaptDHWyrGauge             matlab.ui.control.SemicircularGauge
        NatAdaptDHWyrGaugeLabel        matlab.ui.control.Label
        ByYearDropDown                 matlab.ui.control.DropDown
        ByYearDropDownLabel            matlab.ui.control.Label
        Seed1GaugeLabel_5              matlab.ui.control.Label
        Gauge_5                        matlab.ui.control.SemicircularGauge
        Seed1GaugeLabel_4              matlab.ui.control.Label
        Seed1GaugeLabel_3              matlab.ui.control.Label
        Seed1GaugeLabel_2              matlab.ui.control.Label
        Gauge_4                        matlab.ui.control.SemicircularGauge
        Gauge_2                        matlab.ui.control.SemicircularGauge
        Seed1Gauge                     matlab.ui.control.SemicircularGauge
        Seed1GaugeLabel                matlab.ui.control.Label
        MakeinferenceButton            matlab.ui.control.Button
        AvProvisionalESEditField       matlab.ui.control.EditField
        AvProvisionalESEditFieldLabel  matlab.ui.control.Label
        AvCulturalESEditField          matlab.ui.control.EditField
        AvCulturalESEditFieldLabel     matlab.ui.control.Label
        AvCoralCoverEditField          matlab.ui.control.EditField
        AvCoralCoverEditFieldLabel     matlab.ui.control.Label
        DataTab                        matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shiped with the product.
    
    properties (Access = private)
        % Declare properties of the ADRIA_app_example_2 class.
        Data
        displayedIndices
        counterfIndices
    end
    
    methods (Access = private)
        
        function ScatterPlot(app)
            % function that plots scatter violin plots
            
            % Update X Llbel and declare x data
            app.UIAxes.XLabel.String = 'Years';
            xdata = 10:10:50;
            
            % select ydata based on values selected on interface
            if app.CoralCoverButton.Value
                ydata1 = reshape(app.Data.CoralCover(app.displayedIndices),10,length(app.Data.CoralCover(app.displayedIndices))/10);
                ydata2 = reshape(app.Data.CoralCover(app.counterfIndices),10,length(app.Data.CoralCover(app.counterfIndices))/10);
                app.UIAxes.YLabel.String = 'Coral Cover';
            elseif app.ScopeforCulturalESButton.Value
                ydata1 = reshape(app.Data.Cult_ES(app.displayedIndices),10,length(app.Data.Cult_ES(app.displayedIndices))/10);
                ydata2 = reshape(app.Data.Cult_ES(app.counterfIndices),10,length(app.Data.Cult_ES(app.counterfIndices))/10);
                app.UIAxes.YLabel.String = 'Cultural ES';
            elseif app.ScopeforProvisionalESButton.Value
                ydata1 = reshape(app.Data.Prov_ES(app.displayedIndices),10,length(app.Data.Prov_ES(app.displayedIndices))/10);
                ydata2 = reshape(app.Data.Prov_ES(app.counterfIndices),10,length(app.Data.Prov_ES(app.counterfIndices))/10); 
                app.UIAxes.YLabel.String = 'Provisional ES';
            end
            
            str = cell(size(xdata));
            str2 = str;
            
            % plot violin plot for scenario with intervention
            hold(app.UIAxes,'on')
            [~, mu, sigma, ~, ~] = al_goodplot(ydata1,xdata,app.UIAxes,0.6,[0.5195,0.5977,0]);
            
            % print average value for each year on plot
            for k = 1:length(mu)
                str{k} = sprintf('$\\mu$: %1.3f', mu(k));
            end
            text(app.UIAxes,xdata,mu,str,'VerticalAlignment','bottom','HorizontalAlignment','left','Interpreter','latex','FontSize',12)
            
            hold(app.UIAxes,'off')
            
            % plot violin plot for difference in intervention and no
            % intervention scenarios
            hold(app.UIAxes_2,'on')
            [~, mu2, sigma2, ~, ~] = al_goodplot(ydata1-ydata2,xdata,app.UIAxes_2,0.6);
            
            % print averages
            for k = 1:length(mu2)
                str2{k} = sprintf('$\\mu$: %1.3f',mu2(k));
            end
            text(app.UIAxes_2,xdata,mu2,str2,'VerticalAlignment','bottom','HorizontalAlignment','left','Interpreter','latex','FontSize',12)
            
            % adjust range and domain
            hold(app.UIAxes,'on')
            app.UIAxes.XLim = [5 60];
            app.UIAxes.YLim = [0 max(ydata1,[],'all')+0.01];
            app.UIAxes_2.XLim = [5 60];
            app.UIAxes_2.YLim = [0 max(ydata1-ydata2,[],'all')+0.01];
        end       
        function BBNPlot(app)
            % function to plot BBN with rank correlation measures
            % and histograms for parameter inference
            
            % determine type of data to be plotted
            if app.CoralCoverButton_2.Value
                hist_ind = 1;
            elseif app.ScopeforCulturalESButton_2.Value
                hist_ind = 2;   
            elseif app.ScopeforProvisionalESButton_2.Value
                hist_ind = 3;
            end
            
            % create bbn structure
            Names = {'RCP'; 'Years'; 'Guided'; 'PrSites';'Seed1'; 'Seed2'; 'SRM'; 'AssAdt'; 'NatAdt'; 'CC';'CultES';'ProvES'};
            ParentCell = cell(1,12);
            % first 9 noces have no parents
            for i = 1:9
                ParentCell{i} = [];
            end
            % coral cover and 2 ES have all other nodes as parents
            % initially
            ParentCell{10} = 1:9;
            ParentCell{11} = 1:9;
            ParentCell{12} = 1:9;

            ParentCell2 = ParentCell;
           
            data_bbn = table2array(app.Data);
            % get rid of algorithm indicator for now
            data_bbn(:,2) = [];
            
            % create bbn crank orrelation matrix
            R = bn_rankcorr(ParentCell, data_bbn, 1, 0, Names);
            % second matrix for ploting links with non-zero corr
            R2 = R;
            
            % find links with near zero and zero correlation and get rid of
            % them
            R2(abs(R2)<=10e-4) = NaN;
            R2(abs(R2)==0) = NaN;
            M = 1:9;
            for k = 1:9
                if isnan(R2(k,10))
                    M = M(M~=k);
                    ParentCell2{10} = M;
                end
                if isnan(R2(k,11))
                    M = M(M~=k);
                    ParentCell2{11} = M;
                end
                if isnan(R2(k,12))
                    M = M(M~=k);
                    ParentCell2{12} = M;
                end
            end
            
            % create BBN visualisation
            bn_visualize(ParentCell2,R2,Names,app.UIAxes3)
            str = '';
            str2 = '';
            yrs = 10:10:50;
            F = cell(1,5);
            
            % check if guided and sites selected
            if app.YesCheckBox_2.Value
                Guided = 1;
            else
                Guided = 0;
            end
            
            if app.CCheckBox_2.Value
                PrSites = 3;
            elseif app.ACheckBox.Value
                PrSites = 1;
            else app.BCheckBox.Value
                PrSites = 2;
            end
            
            % perform inference on selected interventions and plot 
            % inference histograms for intervention and
            % non-intervention scenarios
            hold(app.UIAxes2,'on');
            
            % for each year simulated
            for l = 1:length(yrs)
                % retrieve histogram for select metric under selected 
                % intervention scenario
                F{l} = inference([1:9],[str2double(app.RCPKnob.Value)*10,l*10,Guided,PrSites,str2double(app.Seed1DropDown.Value),str2double(app.Seed2DropDown.Value),str2double(app.SRMDropDown.Value),str2double(app.AssAdtDropDown.Value),str2double(app.NatAdtDropDown.Value)],R,data_bbn,'full',1000,'near');
                hist_dat = F{l};
                % plot histogram of resulting distribution for metric (CC
                % or ES)
                h = histogram(app.UIAxes2,hist_dat{hist_ind},'Normalization','probability','NumBins',50);
                % print median and standard deviation for each year
                str = strcat(str,'\n',sprintf('Median (year %2.0f): %1.3f, Std (year %2.0f): %1.3f',yrs(l),median(hist_dat{1}),yrs(l),std(hist_dat{1})));
                str = sprintf(str);
            end
            legend(app.UIAxes2,'year 10','year 20','year 30','year 40','year 50');
            hold(app.UIAxes2_2,'on');
            
            % repeat for non-intervention scenario
            for l = 1:length(yrs)
                F{l} = inference([1:9],[str2double(app.RCPKnob.Value)*10,l*10,Guided,PrSites,0,0,0,0,0],R,data_bbn,'full',1000,'near');
                hist_dat = F{l};
                h = histogram(app.UIAxes2_2,hist_dat{hist_ind},'Normalization','probability','NumBins',50);
                str2 = strcat(str2,'\n',sprintf('Median (year %2.0f): %1.3f, Std (year %2.0f): %1.3f',yrs(l),median(hist_dat{1}),yrs(l),std(hist_dat{1})));                 
                str2 = sprintf(str2);
            end
            
            legend(app.UIAxes2_2,'year 10','year 20','year 30','year 40','year 50');
            
            % populate text box with stats for each hist
            app.TextArea_3.Value = str;
            app.TextArea_4.Value = str2;
        end
        function filterData(app)
            % Utility function to filter the data according to the controls
            
            % Initially assume that all data will be displayed and then, subsequently, filter the data
            % based on the controls
            tempIndices = ones([size(app.Data,1),1]);
            
            % Append a column to tempIndices to indicate data that satisfies the RCP control 
            tempIndices = [tempIndices, app.Data.RCP == str2double(app.RCPKnob.Value)*10];
            
            % Append a column to tempIndices to indicate data that satisfies MCDA control
            tempIndices = [tempIndices, app.Data.MCDAAlgs == str2double(app.MCDADropDown.Value)];
            
            % Append a column to tempIndices to indicate data that satisfies the Guided control 
            if app.YesCheckBox_2.Value && ~app.NoCheckBox_2.Value
                tempIndices = [tempIndices, app.Data.Guided == 1];
            elseif app.NoCheckBox_2.Value && ~app.YesCheckBox_2.Value
                tempIndices = [tempIndices, app.Data.Guided == 0];
            end
            
            % Append a column to tempIndices to indicate data that satisfies the Site control
            if app.BCheckBox.Value && ~app.CCheckBox_2.Value && ~app.ACheckBox.Value
                tempIndices = [tempIndices, app.Data.PrSites == 2];
            elseif app.CCheckBox_2.Value && ~app.BCheckBox.Value && ~app.ACheckBox.Value
                tempIndices = [tempIndices, app.Data.PrSites == 3];
            elseif app.ACheckBox.Value && ~app.BCheckBox.Value && ~app.CCheckBox_2.Value
                tempIndices = [tempIndices, app.Data.PrSites == 1];
            end
            
            % counterfactual indexing will be the same except for
            % intervention indexing
            tempIndices2 = tempIndices;
            
            % Append a column to tempIndices to indicate data that satisfies the Seed1 control
            tempIndices = [tempIndices, app.Data.Seed1 == str2double(app.Seed1DropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.Seed1 == 0];
            
            % Append a column to tempIndices to indicate data that satisfies the Seed2 control
            tempIndices = [tempIndices, app.Data.Seed2 == str2double(app.Seed2DropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.Seed2 == 0];
            
            % Append a column to tempIndices to indicate data that satisfies the SRM control
            tempIndices = [tempIndices, app.Data.SRM == str2double(app.SRMDropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.SRM == 0];
            
            % Append a column to tempIndices to indicate data that satisfies the Assisted Adaptation control
            tempIndices = [tempIndices, app.Data.AssistedAdapt == str2double(app.AssAdtDropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.AssistedAdapt == 0];
            
            % Append a column to tempIndices to indicate data that satisfies the Natural Adaptation control
            tempIndices = [tempIndices, app.Data.NaturalAdapt == str2double(app.NatAdtDropDown.Value)];
            tempIndices2 = [tempIndices2, app.Data.NaturalAdapt == 0];
            
            % Determine which data points satisfy all requirements
            app.displayedIndices = (sum(tempIndices,2)/size(tempIndices,2) == 1);
            app.counterfIndices = (sum(tempIndices2,2)/size(tempIndices2,2) == 1);
        end
        
        function inference_int(app)
            % function for bbn inference on the metrics (CC and ES)
            
            % retrieve parameters for inference
            % scenario
            
            if app.YesCheckBox_2.Value
                Guided = 1;
            else
                Guided = 0;
            end
            
            if app.CCheckBox_2.Value
                PrSites = 3;
            elseif app.ACheckBox.Value
                PrSites = 1;
            else app.BCheckBox.Value
                PrSites = 2;
            end
            % retreive min coral cover, cultural ES and prov, ES values
            CC = str2double(app.AvCoralCoverEditField.Value);
            C_ES = str2double(app.AvCulturalESEditField.Value);
            P_ES = str2double(app.AvProvisionalESEditField.Value);
            year = str2double(app.ByYearDropDown.Value);
            
            % create bbn structure 
            Names = {'RCP'; 'Years'; 'Guided'; 'PrSites';'Seed1'; 'Seed2'; 'SRM'; 'AssAdt'; 'NatAdt'; 'CC';'CultES';'ProvES'};
            ParentCell = cell(1,12);
            for i = 1:9
                ParentCell{i} = [];
            end
            ParentCell{10} = 1:9;
            ParentCell{11} = 1:9;
            ParentCell{12} = 1:9;

            ParentCell2 = ParentCell;
           
            data_bbn = table2array(app.Data);
            data_bbn(:,2) = [];
            
            % create bbn correlation matrix
            R = bn_rankcorr(ParentCell, data_bbn, 1, 0, Names);
            
            % check which boxes (if any) have been left blank, and perform
            % inference accordingly (this can be done much neater but will
            % do for now :'D )
            if (~isnan(CC))&&(~isnan(C_ES))&&(~isnan(P_ES))
                F0 = inference([1:4 10:12],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, CC,C_ES,P_ES],R,data_bbn,'mean',1000,'near');
            elseif isnan(CC)&&(~isnan(C_ES))&&(~isnan(P_ES))
                F0 = inference([1:4 11:12],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, C_ES,P_ES],R,data_bbn,'mean',1000,'near');
                app.AvCoralCoverEditField.Value =F0(7);
            elseif isnan(C_ES)&&(~isnan(CC))&&(~isnan(P_ES))
                F0 = inference([1:4 10,12],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, CC,P_ES],R,data_bbn,'mean',1000,'near');
                app.AvCulturalESEditField.Value =F0(7);
            elseif isnan(P_ES)&&(~isnan(CC))&&(~isnan(C_ES))
                F0 = inference([1:4 10,11],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites, CC,C_ES],R,data_bbn,'mean',1000,'near'); 
                app.AvProvisionalESEditField.Value =F0(6);
            elseif (isnan(CC))&&(~isnan(C_ES))&&(isnan(P_ES))
                F0 = inference([1:4 11],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites,C_ES],R,data_bbn,'mean',1000,'near');
                app.AvCoralCoverEditField.Value =F0(6);
                app.AvProvisionalESEditField.Value =F0(7);
            elseif (~isnan(CC))&&(isnan(C_ES))&&(isnan(P_ES))
                F0 = inference([1:4 10],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites,CC],R,data_bbn,'mean',1000,'near');
                app.AvProvisionalESEditField.Value = F0(7);
                app.AvCulturalESEditField.Value = F0(6);
            elseif (isnan(CC))&&(isnan(C_ES))&&(~isnan(P_ES))
                F0 = inference([1:4 12],[str2double(app.RCPKnob.Value)*10, year, Guided, PrSites,P_ES],R,data_bbn,'mean',1000,'near');  
                app.AvCoralCoverEditField.Value = F0(6);
                app.AvCulturalESEditField.Value = F0(7);
            end
            
            % retrieve inference values for parameters
            F0(F0<0) = 0;
            F0 = round(F0,4);
            app.Seed1Gauge.Value = F0(1);
            app.Gauge_2.Value = F0(2);
            app.NatAdaptDHWyrGauge.Value = F0(5);
            app.Gauge_4.Value = F0(3);
            app.Gauge_5.Value = F0(4);
            
%             hold(app.UIAxes4,'on')
%             for l = 1:2
%                 h = histogram(app.UIAxes4,G0(:,l),'Normalization','probability','NumBins',50);
% %                 str2 = strcat(str2,'\n',sprintf('Median (year %2.0f): %1.3f, Std (year %2.0f): %1.3f',yrs(l),median(hist_dat{1}),yrs(l),std(hist_dat{1})));                 
% %                 str2 = sprintf(str2);
%             end
            
  %          legend(app.UIAxes4,'Seed 1','Seed 2','SRM','Nat. Adpt.','Ass. Adpt.');

      %      hold(app.UIAxes4,'on')

%             hold(app.UIAxes4,'off')
%             h = histogram(app.UIAxes4_2,G0(:,3),'Normalization','probability','NumBins',50);
%             h = histogram(app.UIAxes4_3,G0(:,4),'Normalization','probability','NumBins',50);
%             h = histogram(app.UIAxes4_4,G0(:,5),'Normalization','probability','NumBins',50);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %adjust sizings to fit screen pixels
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.6;
            height = screenHeight*0.6;
            drawnow;
            app.ADRIAOutputDisplayUIFigure.Position = [left bottom width height];
            
            % Store the data in a table and display it in one of the App's tabs.
            app.Data = readtable('ADRIA_BBN_Data.csv');
            app.UITable.Data = app.Data;
        end

        % Button pushed function: AnalysescenarioButton
        function AnalysescenarioButtonPushed(app, event)
          
            % Start with a fresh plot
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes_2)
            cla(app.UIAxes2_2)
            hold(app.UIAxes,'on')
            
            % Filter the data according to the controls
            filterData(app);
            ScatterPlot(app);
            BBNPlot(app);
            
            % Update the table to show only the data that satisfies the controls
            app.UITable.Data = app.Data(app.displayedIndices,:);
            drawnow;
        end

        % Button pushed function: MakeinferenceButton
        function MakeinferenceButtonPushed(app, event)
            inference_int(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ADRIAOutputDisplayUIFigure = uifigure('Visible', 'off');
            app.ADRIAOutputDisplayUIFigure.Name = 'ADRIA Output Display';
            app.GridLayout = uigridlayout(app.ADRIAOutputDisplayUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel2 = uipanel(app.LeftPanel);
            app.Panel2.Title = 'Guided';
            app.NoCheckBox_2 = uicheckbox(app.Panel2);
            app.NoCheckBox_2.Text = 'No';
            app.YesCheckBox_2 = uicheckbox(app.Panel2);
            app.YesCheckBox_2.Text = 'Yes';
            app.YesCheckBox_2.Value = true;
            app.Panel2_2 = uipanel(app.LeftPanel);
            app.Panel2_2.Title = 'Sites';
            app.CCheckBox_2 = uicheckbox(app.Panel2_2);
            app.CCheckBox_2.Text = 'C';
            app.CCheckBox_2.Value = true;
            app.ACheckBox = uicheckbox(app.Panel2_2);
            app.ACheckBox.Text = 'A';
            app.BCheckBox = uicheckbox(app.Panel2_2);
            app.BCheckBox.Text = 'B';
            app.Panel2_4 = uipanel(app.LeftPanel);
            app.Panel2_4.Title = 'Seeding/SRM';
            app.Seed1DropDownLabel = uilabel(app.Panel2_4);
            app.Seed1DropDownLabel.Text = 'Seed 1';
            app.Seed1DropDown = uidropdown(app.Panel2_4);
            app.Seed1DropDown.Items = {'0', '0.005', '0.001'};
            app.Seed1DropDown.Value = '0';
            app.Seed2DropDownLabel = uilabel(app.Panel2_4);
            app.Seed2DropDownLabel.Text = 'Seed 2';
            app.Seed2DropDown = uidropdown(app.Panel2_4);
            app.Seed2DropDown.Items = {'0', '0.0005', '0.001'};
            app.Seed2DropDown.Value = '0';
            app.SRMDropDownLabel = uilabel(app.Panel2_4);
            app.SRMDropDownLabel.Text = 'SRM';
            app.SRMDropDown = uidropdown(app.Panel2_4);
            app.SRMDropDown.Items = {'0', '6', '12'};
            app.SRMDropDown.Value = '0';
            app.ScenarioSelectionLabel = uilabel(app.LeftPanel);
            app.ScenarioSelectionLabel.Text = 'Scenario Selection';
            app.Panel2_5 = uipanel(app.LeftPanel);
            app.Panel2_5.Title = 'Ass./Nat. Adapt.';
            app.AssAdtDropDownLabel = uilabel(app.Panel2_5);
            app.AssAdtDropDownLabel.Text = 'Ass. Adt.';
            app.AssAdtDropDown = uidropdown(app.Panel2_5);
            app.AssAdtDropDown.Items = {'0', '6', '12', ''};
            app.AssAdtDropDown.Value = '0';
            app.NatAdtDropDownLabel = uilabel(app.Panel2_5);
            app.NatAdtDropDownLabel.Text = 'Nat. Adt.';
            app.NatAdtDropDown = uidropdown(app.Panel2_5);
            app.NatAdtDropDown.Items = {'0', '0.05', '0.1'};
            app.NatAdtDropDown.Value = '0';
            app.RCPKnobLabel = uilabel(app.LeftPanel);
            app.RCPKnobLabel.Text = 'RCP';
            app.RCPKnob = uiknob(app.LeftPanel, 'discrete');
            app.RCPKnob.Items = {'2.6', '4.5', '6.0', '8.5'};
            app.RCPKnob.Value = '2.6';
            app.AnalysescenarioButton = uibutton(app.LeftPanel, 'push');
            app.AnalysescenarioButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysescenarioButtonPushed, true);
            app.AnalysescenarioButton.Text = 'Analyse scenario';
            app.Panel2_6 = uipanel(app.LeftPanel);
            app.Panel2_6.Title = 'Ass./Nat. Adapt.';
            app.MCDADropDownLabel = uilabel(app.Panel2_6);
            app.MCDADropDownLabel.Text = 'MCDA';
            app.MCDADropDown = uidropdown(app.Panel2_6);
            app.MCDADropDown.Items = {'1', '2', '3'};
            app.MCDADropDown.Value = '1';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.TimeSeriesTab = uitab(app.TabGroup);
            app.TimeSeriesTab.Title = 'Time Series';
            app.UIAxes = uiaxes(app.TimeSeriesTab);
            title(app.UIAxes, 'With Intervention', 'Interpreter', 'latex')
            xlabel(app.UIAxes, 'Years', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Coral Cover/ES ', 'Interpreter', 'latex')
            app.UIAxes_2 = uiaxes(app.TimeSeriesTab);
            title(app.UIAxes_2, 'Intervention-counterfactural', 'Interpreter', 'latex')
            xlabel(app.UIAxes_2, 'Years', 'Interpreter', 'latex')
            ylabel(app.UIAxes_2, 'Difference', 'Interpreter', 'latex')
            app.MetricButtonGroup = uibuttongroup(app.TimeSeriesTab);
            app.MetricButtonGroup.Title = 'Metric';
            app.CoralCoverButton = uiradiobutton(app.MetricButtonGroup);
            app.CoralCoverButton.Text = 'Coral Cover';
            app.CoralCoverButton.Value = true;
            app.ScopeforCulturalESButton = uiradiobutton(app.MetricButtonGroup);
            app.ScopeforCulturalESButton.Text = 'Scope for Cultural ES ';
            app.ScopeforProvisionalESButton = uiradiobutton(app.MetricButtonGroup);
            app.ScopeforProvisionalESButton.Text = 'Scope for Provisional ES';
            app.BBNTab = uitab(app.TabGroup);
            app.BBNTab.Title = 'BBN';
            app.UIAxes3 = uiaxes(app.BBNTab);
            title(app.UIAxes3, 'BBN Rank Correlations', 'Interpreter', 'latex')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.InterventionInferenceTab = uitab(app.TabGroup);
            app.InterventionInferenceTab.Title = 'Intervention Inference';
            app.UIAxes2 = uiaxes(app.InterventionInferenceTab);
            xlabel(app.UIAxes2, 'Coral Cover/ES', 'Interpreter', 'latex')
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes2_2 = uiaxes(app.InterventionInferenceTab);
            xlabel(app.UIAxes2_2, 'Coral Cover/ES', 'Interpreter', 'latex')
            app.UIAxes2_2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2_2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2_2.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.TextArea_3Label = uilabel(app.InterventionInferenceTab);
            app.TextArea_3Label.Text = '';
            app.TextArea_3 = uitextarea(app.InterventionInferenceTab);
            app.MetricButtonGroup_2 = uibuttongroup(app.InterventionInferenceTab);
            app.MetricButtonGroup_2.Title = 'Metric';
            app.CoralCoverButton_2 = uiradiobutton(app.MetricButtonGroup_2);
            app.CoralCoverButton_2.Text = 'Coral Cover';
            app.CoralCoverButton_2.Value = true;
            app.ScopeforCulturalESButton_2 = uiradiobutton(app.MetricButtonGroup_2);
            app.ScopeforCulturalESButton_2.Text = 'Scope for Cultural ES ';
            app.ScopeforProvisionalESButton_2 = uiradiobutton(app.MetricButtonGroup_2);
            app.ScopeforProvisionalESButton_2.Text = 'Scope for Provisional ES';
            app.TextArea_4 = uitextarea(app.InterventionInferenceTab);
            app.WithInterventionLabel = uilabel(app.InterventionInferenceTab);
            app.WithInterventionLabel.Text = 'With Intervention';
            app.WithoutInterventionLabel = uilabel(app.InterventionInferenceTab);
            app.WithoutInterventionLabel.Text = 'Without Intervention';
            app.MetricInferenceTab = uitab(app.TabGroup);
            app.MetricInferenceTab.Title = 'Metric Inference';
            app.AvCoralCoverEditFieldLabel = uilabel(app.MetricInferenceTab);
            app.AvCoralCoverEditFieldLabel.Text = 'Av. Coral Cover';
            app.AvCoralCoverEditField = uieditfield(app.MetricInferenceTab, 'text');
            app.AvCulturalESEditFieldLabel = uilabel(app.MetricInferenceTab);
            app.AvCulturalESEditFieldLabel.Text = 'Av. Cultural ES ';
            app.AvCulturalESEditField = uieditfield(app.MetricInferenceTab, 'text');
            app.AvProvisionalESEditFieldLabel = uilabel(app.MetricInferenceTab);
            app.AvProvisionalESEditFieldLabel.Text = 'Av. Provisional ES ';
            app.AvProvisionalESEditField = uieditfield(app.MetricInferenceTab, 'text');
            app.MakeinferenceButton = uibutton(app.MetricInferenceTab, 'push');
            app.MakeinferenceButton.ButtonPushedFcn = createCallbackFcn(app, @MakeinferenceButtonPushed, true);
            app.MakeinferenceButton.Text = 'Make inference';
            app.Seed1GaugeLabel = uilabel(app.MetricInferenceTab);
            app.Seed1GaugeLabel.Text = 'Seed1';
            app.Seed1Gauge = uigauge(app.MetricInferenceTab, 'semicircular');
            app.Seed1Gauge.Limits = [0 0.001];
            app.Gauge_2 = uigauge(app.MetricInferenceTab, 'semicircular');
            app.Gauge_2.Limits = [0 0.001];
            app.Gauge_4 = uigauge(app.MetricInferenceTab, 'semicircular');
            app.Gauge_4.Limits = [0 12];
            app.Seed1GaugeLabel_2 = uilabel(app.MetricInferenceTab);
            app.Seed1GaugeLabel_2.Text = 'Seed2';
            app.Seed1GaugeLabel_3 = uilabel(app.MetricInferenceTab);
            app.Seed1GaugeLabel_3.Text = '';
            app.Seed1GaugeLabel_4 = uilabel(app.MetricInferenceTab);
            app.Seed1GaugeLabel_4.Text = 'SRM (DHW)';
            app.Gauge_5 = uigauge(app.MetricInferenceTab, 'semicircular');
            app.Gauge_5.Limits = [0 12];
            app.Seed1GaugeLabel_5 = uilabel(app.MetricInferenceTab);
            app.Seed1GaugeLabel_5.Text = 'Assist. Adapt. (DHW)';
            app.ByYearDropDownLabel = uilabel(app.MetricInferenceTab);
            app.ByYearDropDownLabel.Text = 'By Year';
            app.ByYearDropDown = uidropdown(app.MetricInferenceTab);
            app.ByYearDropDown.Items = {'10', '20', '30', '40', '50'};
            app.ByYearDropDown.Value = '10';
            app.NatAdaptDHWyrGaugeLabel = uilabel(app.MetricInferenceTab);
            app.NatAdaptDHWyrGaugeLabel.Text = 'Nat. Adapt. (DHW/yr)';
            app.NatAdaptDHWyrGauge = uigauge(app.MetricInferenceTab, 'semicircular');
            app.NatAdaptDHWyrGauge.Limits = [0 0.1];
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'RCP'; 'MCDAAlgs'; 'Years'; 'Guided'; 'PrSites'; 'Seed1'; 'Seed2'; 'SRM'; 'AssAdt'; 'NatAdt'; 'CoralCover'; 'Cult_ES'; 'Prov_ES'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            % Show the figure after all components are created
            app.ADRIAOutputDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADRIA_app_example_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ADRIAOutputDisplayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ADRIAOutputDisplayUIFigure)
        end
    end
end